classdef FinalProjectDraft5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_7                         matlab.ui.control.Label
        MainSampleLastSelectedLabel     matlab.ui.control.Label
        SpectrogramViewLabel            matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        SaveSelectedSampleasFileButton  matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        AudioSplicingPanel              matlab.ui.container.Panel
        SampleSavedToDropDown_4         matlab.ui.control.DropDown
        SampleSavedToDropDown_4Label    matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        BaseSampleButtonGroup           matlab.ui.container.ButtonGroup
        Button_3                        matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button                          matlab.ui.control.RadioButton
        EditField_3                     matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        EditField                       matlab.ui.control.EditField
        SampleDropDown_8                matlab.ui.control.DropDown
        SampleDropDown_7                matlab.ui.control.DropDown
        SaveButton_2                    matlab.ui.control.Button
        SpliceAudioButton               matlab.ui.control.Button
        SampleDropDown_4                matlab.ui.control.DropDown
        StartTimeLabel                  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        SynthesizerPanel                matlab.ui.container.Panel
        SampleSavedToDropDown_3         matlab.ui.control.DropDown
        SampleSavedToDropDown_3Label    matlab.ui.control.Label
        PlayButton                      matlab.ui.control.Button
        FrequencySlider                 matlab.ui.control.Slider
        FrequencySliderLabel            matlab.ui.control.Label
        SaveButton_5                    matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SquareWaveButton                matlab.ui.control.RadioButton
        SineWaveButton                  matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        ChoppingPanel                   matlab.ui.container.Panel
        SampleSavedToDropDown           matlab.ui.control.DropDown
        SampleSavedToDropDownLabel      matlab.ui.control.Label
        StoptimesecEditField            matlab.ui.control.NumericEditField
        StoptimesecEditFieldLabel       matlab.ui.control.Label
        StarttimesecEditField           matlab.ui.control.NumericEditField
        StarttimesecEditFieldLabel      matlab.ui.control.Label
        ChopAudioButton                 matlab.ui.control.Button
        SaveButton_7                    matlab.ui.control.Button
        SampleUsedDropDown              matlab.ui.control.DropDown
        SampleUsedDropDownLabel         matlab.ui.control.Label
        EffectsAndSampleModificationPanel  matlab.ui.container.Panel
        SampleSavedToDropDown_2         matlab.ui.control.DropDown
        SampleSavedToDropDown_2Label    matlab.ui.control.Label
        SaveButton_6                    matlab.ui.control.Button
        PlayButton_2                    matlab.ui.control.StateButton
        DelayTimesecKnob                matlab.ui.control.Knob
        DelayTimesecKnobLabel           matlab.ui.control.Label
        FeedbackLevelKnob               matlab.ui.control.Knob
        FeedbackLevelLabel              matlab.ui.control.Label
        EqualizerPanel                  matlab.ui.container.Panel
        kHzSlider_2                     matlab.ui.control.Slider
        kHzLabel                        matlab.ui.control.Label
        kHzSlider                       matlab.ui.control.Slider
        kHzSliderLabel                  matlab.ui.control.Label
        HzSlider_3                      matlab.ui.control.Slider
        HzSlider_3Label                 matlab.ui.control.Label
        HzSlider_2                      matlab.ui.control.Slider
        HzSlider_2Label                 matlab.ui.control.Label
        HzSlider_4                      matlab.ui.control.Slider
        HzSlider_4Label                 matlab.ui.control.Label
        HzSlider                        matlab.ui.control.Slider
        HzSliderLabel                   matlab.ui.control.Label
        PlaybackSpeedSlider             matlab.ui.control.Slider
        PlaybackSpeedSliderLabel        matlab.ui.control.Label
        PlaybackDirectionSwitch         matlab.ui.control.Switch
        PlaybackDirectionSwitchLabel    matlab.ui.control.Label
        VolumeKnob                      matlab.ui.control.Knob
        VolumeKnobLabel                 matlab.ui.control.Label
        SampleDropDown_3                matlab.ui.control.DropDown
        SampleDropDown_3Label           matlab.ui.control.Label
        SamplesSelectionPanel           matlab.ui.container.Panel
        LoadButton_1                    matlab.ui.control.Button
        LoadButton_2                    matlab.ui.control.Button
        LoadButton_3                    matlab.ui.control.Button
        LoadButton_4                    matlab.ui.control.Button
        LoadButton_5                    matlab.ui.control.Button
        LoadButton_6                    matlab.ui.control.Button
        LoadButton_7                    matlab.ui.control.Button
        LoadButton_8                    matlab.ui.control.Button
        LoadButton_9                    matlab.ui.control.Button
        Sample1Button                   matlab.ui.control.Button
        Sample2Button                   matlab.ui.control.Button
        Sample3Button                   matlab.ui.control.Button
        Sample4Button                   matlab.ui.control.Button
        Sample5Button                   matlab.ui.control.Button
        Sample6Button                   matlab.ui.control.Button
        Sample7Button                   matlab.ui.control.Button
        Sample8Button                   matlab.ui.control.Button
        Sample9Button                   matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        %These are all blank, but the first one shows what is stored
        %get the point across. Data is the signal
        %Rate is the sample rate.
        %Player is an audioplayer object for the two prior pieces of data
        % ,which lets you do a bunch of cool stuff with audio.
        %sample1name is just the name of the audio file
        %sample1info is the result of an audioinfo on file.
        %It lets you know the length of the audio, which is nice

        sample1; %= {sample1Data,sample1Rate,sample1player,sample1name,sample1info}

        sample2;

        sample3;

        sample4;

        sample5;

        sample6;

        sample7;

        sample8;

        sample9;

        %used in drop down menus
        sampleArray = {1,2,3,4,5,6,7,8,9};
           
        %used in Samples Selection
        currentSample;
        currentSampleDisplayed;

        %used in the start stop button
        startButtonPushed = false;

        %used to reverse audio
        reversePlayback = false;

        %used to adjust playback speed
        playbackSpeed = 1;
        
        %used to adjust feedback
        feedbackLevel = 0;

        %used for volume adjustment
        volumeLevel = 1;
        
        %used to delay playback
        delayTime = 0;

        choppedAudio;

        hertzSliderValue = 25;

        %1 = sine wave, 2 = square wave
        waveSelected = 1;

        %used in the synthesizer
        generatedSample;

        %used in the modifier
        modifierSample;

        %equilizer
        eqGains = struct('Hz50', 1, 'Hz125', 1, 'Hz250', 1, 'Hz500', 1, 'Hz1000', 1, 'Hz2000', 1);
        
        %Used in the Audio Splicer
        %numvalue is the length the user puts into the edit field
        %samplevalue is the length of the audio sample
        %gotta make sure numvalue isnt lar
        numValueEF1;
        numValueEF2;
        numValueEF3;

        %sample values used for now, maybe used for adjusting labels
        sampleValueEF1 = -1;
        sampleValueEF2 = -1;
        sampleValueEF3 = -1;

        %this is used and important
        selectedBaseSample = 1;

        %the selected sample is the first index, the other two are actually
        %non selected.
        nonSelectedBaseSamples = [1,2,3];

        %Where the spliced sample is stored (crazy stuff)
        splicedSample;

        %used in the spectrogram
        displaySpectrogram = false;
        startValueEFS;
        stopValueEFS;
        image;

       
    end
    
    methods (Access = private)
        
        function [newCellArray] = assignMusicFile(app)
            %This assigns a new cell array for the sample above
             [filename,filepath] =uigetfile({'*.wav;*.mp3;'}, 'Select File to Open');

             %If the user cancels out, then return the default
             if isequal(filename, 0) 
                errordlg('File selection was cancelled', 'Error');
                newCellArray = 0;
                return;
             
             else
            
              
             [newCellArray{1},newCellArray{2}] = audioread([filepath, filename]);
             newCellArray{3} = audioplayer(newCellArray{1},newCellArray{2});
             newCellArray{4} = filename;
             newCellArray{5} = audioinfo([filepath, filename]);

             end

        end

        %Every slot in itemsData is supposed to contain a cell array. This
        %cell array contains the sample in the first index and an index
        %number in the second index. So after filling all samples, itemsData
        %will have 9 cell arrays in a cell array (9 nested cell Arrays)
        %
        %Items, on the other hand, is just the name of the sample on the
        %dropdown
        function [Items, ItemsData]= addDropDownValue(app,indexNum, dropDown)
            %If the data is empty, then we simply add the point in
            %Also every data point has a sample AND a index with it
            sampleName = ['Sample ', num2str(indexNum)];

            ItemsData = dropDown.ItemsData;
            Items = dropDown.Items;

            if (ItemsData{1}{2} == 0)
                Items = {sampleName};
                ItemsData = {{app.sampleArray{indexNum},indexNum}};
            else
                %If it isnt empty
                for i = 1:length(ItemsData)
                    %check through every cell
                        
                    %If the index is the same as data point checked,
                    %then replace the sample there with the new sample
                    
                    if (ItemsData{i}{2} == indexNum)

                        ItemsData{i}{1} = app.sampleArray{indexNum};
                        break;
                         
                     %If the index is smaller than the one it is currently
                     %at
                    elseif (ItemsData{i}{2} > indexNum)
                        %if the previous data point doesnt exist, then you
                        %become that data point. IE get added to the end of
                        %the list. If it does exist, you go between the
                        %current point you are at and the point before it.
                        if (i-1 == 0)
                               ItemsData = [{{app.sampleArray{indexNum},indexNum}},ItemsData];
                               Items = [{sampleName}, Items];
                        else
                               ItemsData = [ItemsData(1:i-1), {{app.sampleArray{indexNum},indexNum}}, ItemsData(i:end)];

                               Items = [Items(1:i-1), {sampleName},Items(i:end)];
                        end
                        %If your index is bigger than the one you are
                        %currently at: If the one in front of you doesnt
                        %exist, you become that one. If the one in front of
                        %you is larger, you go in the middle. If the one in
                        %front of you is smaller, continue the loop.
                    else %(app.SampleDropDown_3.ItemsData{i}{2} < indexNum)
                        if (i+1 == length(ItemsData) + 1)

                            ItemsData =...
                                [ItemsData, {{app.sampleArray{indexNum},indexNum}} ];

                            Items = [Items,{sampleName}];

                        elseif (ItemsData{i+1}{2} > indexNum)
                             ItemsData = [ItemsData(1:i), {{app.sampleArray{indexNum},indexNum}}, ItemsData(i+1:end)];

                             Items = [Items(1:i), {sampleName}, Items(i+1:end)];
                             
                        else
                            continue;
                        end

                    end
                end
            end
            function outputAudio = simpleEqualization(app, audioData, sampleRate, frequency)
        % SIMPLEEQUALIZATION - Applies a basic EQ effect by boosting/cutting frequencies
        % without using advanced filters.
        % INPUTS:
        %   audioData - The input audio signal
        %   sampleRate - The sample rate of the audio
        %   frequency - The user-selected frequency to boost or cut
        % OUTPUT:
        %   outputAudio - The processed audio signal

        numChannels = size(audioData, 2);  % Check if stereo or mono
        outputAudio = zeros(size(audioData));  % Preallocate output

        for ch = 1:numChannels
            fftAudio = fft(audioData(:, ch));  % Compute FFT per channel
            freqAxis = linspace(0, sampleRate, length(audioData));  % Frequency axis

            % Define gain curve for EQ
            gainCurve = ones(size(freqAxis));

            if frequency < 500
                gainCurve(freqAxis < frequency) = 1.5;  % Boost low frequencies
                gainCurve(freqAxis > frequency) = 0.8;  % Slightly reduce highs
            else
                gainCurve(freqAxis > frequency) = 1.5;  % Boost highs
                gainCurve(freqAxis < frequency) = 0.8;  % Slightly reduce lows
            end

            % Apply gain curve symmetrically
            fftAudio = fftAudio .* gainCurve';
            fftAudio = fftAudio .* conj(gainCurve'); 

            % Convert back to time domain
            outputAudio(:, ch) = real(ifft(fftAudio));
        end

        % Normalize the processed audio
        if max(abs(outputAudio(:))) > 0
            outputAudio = outputAudio / max(abs(outputAudio(:)));
        end
    end

        end


        function updateAllDropDowns(app, number)
            [app.SampleUsedDropDown.Items, app.SampleUsedDropDown.ItemsData]= addDropDownValue(app,number, app.SampleDropDown_3);
            [app.SampleDropDown_3.Items, app.SampleDropDown_3.ItemsData]= addDropDownValue(app,number, app.SampleDropDown_3);
            [app.SampleDropDown_4.Items, app.SampleDropDown_4.ItemsData]= addDropDownValue(app,number, app.SampleDropDown_3);
            [app.SampleDropDown_7.Items, app.SampleDropDown_7.ItemsData]= addDropDownValue(app,number, app.SampleDropDown_3);
            [app.SampleDropDown_8.Items, app.SampleDropDown_8.ItemsData]= addDropDownValue(app,number, app.SampleDropDown_3);
        end
        
        function sampleButtonPushed(app,index)
            %Used for when you push a sample button, so that it can be
            %graphed and played
            if (~isa(app.sampleArray{index},'numeric'))
                % Stores a really long array with the time at each
                % data point in the song
            time = (0:length(app.sampleArray{index}{1})-1)...
                /app.sampleArray{index}{2};

            app.currentSample = app.sampleArray{index};
            plot(app.UIAxes2, time , app.sampleArray{index}{1});  
    
            % Set labels and title for the plot
            if app.displaySpectrogram
                    windowLength = 1024; % Length of each window for STFT
                    overlapLength = windowLength / 2; % 50% overlap
                    nfft = 2048; % Number of FFT points
                    
                    %Plots only the left array
                    
                    [s, f, t] = spectrogram(app.sampleArray{index}{1}(:,1), windowLength, overlapLength, nfft, app.sampleArray{index}{2});
                    
                    %Plot the spectrogram
                    axes(app.UIAxes2); % Make sure UIAxes2 is the active axes
                    imagesc(app.UIAxes2,t, f, 10*log10(abs(s).^2)); % Plot in dB scale
                    xlabel(app.UIAxes2,'Time (s)');
                    ylabel(app.UIAxes2,'Frequency (Hz)');
                    title(app.UIAxes2,'Spectrogram');
            else
                xlabel(app.UIAxes2, 'Time (seconds)');
                ylabel(app.UIAxes2, 'Amplitude');
                title(app.UIAxes2, 'Waveform');
            end
                app.currentSampleDisplayed = index;
                app.Label_7.Text = num2str(index);
            end  
        end


            function makeAndGraphSynth(app)
                if app.waveSelected == 1
                %Sine Wave
              
                fs = 44100;     % Sample rate 
                f = app.hertzSliderValue; %Frequency
                duration = 5;   % Duration of the sound in seconds
                amplitude = 0.5; % Amplitude 
                
                %time vector
                t = 0:1/fs:duration;  
                
                % Generate the sine wave
                y = amplitude * sin(2 * pi *f*t); 
                y = y';
                
                %Data
                app.generatedSample{1} = y;
                %Sample Rate
                app.generatedSample{2} = fs;
                %audioplayer
                app.generatedSample{3} = audioplayer(y,fs);
                %name
                app.generatedSample{4} = 'sine_wave.wav';
                
                % The fifth index of a sample contains AudioInfo
                % which is a struct has a bunch of parameter. These
                %are those parameters. Copy but change the title.
                %This isn't optional btw, other code uses this and
                %stuff will mess up if you don't copy these over.
                
                app.generatedSample{5}.Filename =  'sine_wave.wav';
                app.generatedSample{5}.CompressionMethod =  'Uncompressed';
                app.generatedSample{5}.NumChannels =  1;
                app.generatedSample{5}.SampleRate =  fs;
                app.generatedSample{5}.TotalSamples =  length(y);
                app.generatedSample{5}.Duration =  duration;
                app.generatedSample{5}.Title =  [];
                app.generatedSample{5}.Comment =  [];
                app.generatedSample{5}.Artist =  [];
                app.generatedSample{5}.BitsPerSample =  16;
                
                plot(app.UIAxes, t, y);     %waveform ploting
                xlabel(app.UIAxes, 'Time (seconds)');
                ylabel(app.UIAxes, 'Amplitude');
                title(app.UIAxes, 'Waveform');

            elseif app.waveSelected == 2

                %Square Wave

                fs = 44100;       % Sample rate 
                f = app.hertzSliderValue;          
                duration = 5;     % Duration of the square wave in seconds
                amplitude = 0.5;  % Amplitude 
                
                t = 0:1/fs:duration;  % Time vector
                
                % Generate the sine wave
                y = amplitude * sin(2 * pi * f * t);
                
                % Convert the sine wave to a square wave using the sign function
                y = amplitude * sign(y); 
                y = y';


                %Data
                app.generatedSample{1} = y;
                %Sample Rate
                app.generatedSample{2} = fs;
                %audioplayer
                app.generatedSample{3} = audioplayer(y,fs);
                %name
                app.generatedSample{4} = 'square_wave.wav';
                
                % The fifth index of a sample contains AudioInfo
                % which is a struct has a bunch of parameter. These
                %are those parameters. Copy but change the title.
                %This isn't optional btw, other code uses this and
                %stuff will mess up if you don't copy these over.
                
                app.generatedSample{5}.Filename =  'square_wave.wav';
                app.generatedSample{5}.CompressionMethod =  'Uncompressed';
                app.generatedSample{5}.NumChannels =  1;
                app.generatedSample{5}.SampleRate =  fs;
                app.generatedSample{5}.TotalSamples =  length(y);
                app.generatedSample{5}.Duration =  duration;
                app.generatedSample{5}.Title =  [];
                app.generatedSample{5}.Comment =  [];
                app.generatedSample{5}.Artist =  [];
                app.generatedSample{5}.BitsPerSample =  16;
                
                plot(app.UIAxes, t, y);     %waveform ploting
                xlabel(app.UIAxes, 'Time (seconds)');
                ylabel(app.UIAxes, 'Amplitude');
                title(app.UIAxes, 'Waveform');

            end
        end
        
        function saveToSample(app,value,sample)
            

            switch value
                case 1
                    app.sample1 = sample;
                    app.Sample1Button.Text = sample{4};
                    app.sampleArray{1} = sample;
                    updateAllDropDowns(app,1);

                case 2
                    app.sample2 = sample;
                    app.Sample2Button.Text = sample{4};
                    app.sampleArray{2} = sample;
                    updateAllDropDowns(app,2);

                case 3
                    app.sample3 = sample;
                    app.Sample3Button.Text = sample{4};
                    app.sampleArray{3} = sample;
                    updateAllDropDowns(app,3);

                case 4
                   
                    app.sample4 = sample;
                    app.Sample4Button.Text = sample{4};
                    app.sampleArray{4} = sample;
                    updateAllDropDowns(app,4);

                case 5
                    app.sample5 = sample;
                    app.Sample5Button.Text = sample{4};
                    app.sampleArray{5} = sample;
                    updateAllDropDowns(app,5);

                case 6
                    app.sample6 = sample;
                    app.Sample6Button.Text = sample{4};
                    app.sampleArray{6} = sample;
                    updateAllDropDowns(app,6);

                case 7
                    app.sample7 = sample;
                    app.Sample7Button.Text = sample{4};
                    app.sampleArray{7} = sample;
                    updateAllDropDowns(app,7);

                case 8
                    app.sample8 = sample;
                    app.Sample8Button.Text = sample{4};
                    app.sampleArray{8} = sample;
                    updateAllDropDowns(app,8);

                case 9
                    app.sample9 = sample;
                    app.Sample9Button.Text = sample{4};
                    app.sampleArray{9} = sample;
                    updateAllDropDowns(app,9);

            end
        end
        
        
           function outputAudio = simpleEqualization(app, audioData, sampleRate)
                    numChannels = size(audioData, 2);                       %determine mono(1)/stereo(2)
                    outputAudio = zeros(size(audioData));  
                    freqBands = [50, 125, 250, 500, 1000, 2000];            %split into these frequency bands
                    gains = [app.eqGains.Hz50, app.eqGains.Hz125, app.eqGains.Hz250, ...
                    app.eqGains.Hz500, app.eqGains.Hz1000, app.eqGains.Hz2000];     %gain values

                for ch = 1:numChannels
                    fftAudio = fft(audioData(:, ch));                       %convert from time to frequency
                    freqAxis = linspace(0, sampleRate, length(audioData));  
                    gainCurve = ones(size(freqAxis));
                    bandMask = abs(freqAxis - freqBands') < (freqBands' * 0.5); 
                    gainCurve = sum(bandMask .* gains', 1); 
    
                    fftAudio = fftAudio .* gainCurve';                      %apply gain curve
                    fftAudio = fftAudio .* conj(gainCurve'); 
    
                    outputAudio(:, ch) = real(ifft(fftAudio));
                end
    
                    maxVal = max(abs(outputAudio(:)));                      %again, normalize to prevent clipping [-1,1]
                 if maxVal > 0
                    outputAudio = outputAudio / maxVal;
                 end
             end


         function applyEqualization(app)
        
                if ~isempty(app.currentSample)
                    audioData = app.currentSample{1};  
                    sampleRate = app.currentSample{2};  
                    filteredAudio = simpleEqualization(app, audioData, sampleRate);   %modifies audio and sends it in filtered audio
        
                if max(abs(filteredAudio(:))) > 0
                        filteredAudio = filteredAudio / max(abs(filteredAudio(:)));
                end
        
                   app.currentSample{1} = filteredAudio;
                   app.currentSample{3} = audioplayer(filteredAudio, sampleRate);    %update current sample
        
                   time = (0:length(filteredAudio) - 1) / sampleRate;       %converts to seconds
                    darkGreen = [0, 0.5, 0];
                   if app.displaySpectrogram
                   windowLength = 1024; 
                   overlapLength = windowLength / 2; 
                   nfft = 2048; 
                         
                   [s, f, t] = spectrogram(filteredAudio(:,1), windowLength, overlapLength, nfft, sampleRate);
                            
                   axes(app.UIAxes2); 
                   imagesc(app.UIAxes2,t, f, 10*log10(abs(s).^2));          %plot spectrogram/waveform
                   xlabel(app.UIAxes2,'Time (seconds)');
                   ylabel(app.UIAxes2,'Frequency (Hz)');
                   title(app.UIAxes2,'Equalized Spectrogram');
                   else
                   plot(app.UIAxes2, time, filteredAudio, 'Color', darkGreen);
                   xlabel(app.UIAxes2, 'Time (seconds)');
                   ylabel(app.UIAxes2, 'Amplitude');
                   title(app.UIAxes2, 'Equalized Waveform');
                   end
        
                end
               
           end
        
        function [tempNum, newFieldValue] = editFieldCallbackProcesses...
                (app,dropDownValue,fieldValue)

            if  (dropDownValue{2} ~= 0)
            %find all numerical characters
            numericalChars = regexp(fieldValue, '\d', 'match');
            
            % Combine the numerical characters into a single string
            %If the user puts in nothing, then its zero

            if (~isempty(numericalChars))
                combinedString = strcat(numericalChars{:});
    
                tempNum = str2double(combinedString);
            else
                tempNum = 0;
            end
            
            %This kinda sucks, but I gotta base the max time off the
            %selected sample, and this is the easiest way to know A sample
            %is selected at all
        
            if (app.selectedBaseSample == 1)
                timeSample = length(app.SampleDropDown_4.Value{1}{1})/ app.SampleDropDown_4.Value{1}{2};
            elseif app.selectedBaseSample == 2
                timeSample = length(app.SampleDropDown_7.Value{1}{1})/ app.SampleDropDown_7.Value{1}{2};
            else
                timeSample = length(app.SampleDropDown_8.Value{1}{1})/ app.SampleDropDown_8.Value{1}{2};
            end

            if (round(timeSample) <= tempNum)
                tempNum = [];
                newFieldValue = "";
                uialert(app.UIFigure, ['The start time of the spliced ' ...
                    'sample must be less than the length of the base sample.'], 'Error');
                return;
            end

            minutes = floor(tempNum / 60);
            seconds = mod(tempNum, 60);

            newFieldValue = sprintf('%01d:%02d', minutes, seconds);
    

            else
                tempNum = [];
                newFieldValue = "";
           end
            
        end


        %End of methods
        
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          %Copying from above:
          %Every slot in itemsData is supposed to contain a cell array. This
          %cell array contains the sample in the first index and an index
          %number in the second index. So after filling all samples, itemsData
          %will have 9 cell arrays in a cell array (9 nested cell Arrays).
          %
          %Currently all these slots are placeholders, hence the zero value
          %in the second index. This is used to get them replaced.
          %
          %Items, on the other hand, is just the name of the sample on the
          %dropdown
            app.SampleUsedDropDown.ItemsData = {{ {[1,2,3],3}, 0}};
            app.SampleUsedDropDown.ItemsData = {{ {[1,2,3],3}, 0}};
            app.SampleDropDown_3.ItemsData = {{ {[1,2,3],3}, 0}};
            app.SampleDropDown_4.ItemsData = {{ {[1,2,3],3}, 0}};
            app.SampleDropDown_7.ItemsData = {{ {[1,2,3],3}, 0}};
            app.SampleDropDown_8.ItemsData = {{ {[1,2,3],3}, 0}};


          %This is set up for the synethesizer
           makeAndGraphSynth(app);


        end

        % Button pushed function: LoadButton_1
        function LoadButton_1Pushed(app, event)
            
            placeholder =  assignMusicFile(app);

            if ~isequal(placeholder, 0) 
            app.sample1 = placeholder;
            app.Sample1Button.Text = app.sample1{4};
            app.sampleArray{1} = app.sample1;

            updateAllDropDowns(app,1);
            end
            
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            placeholder =  assignMusicFile(app);

            if ~isequal(placeholder, 0)
            app.sample2 = placeholder;
            app.Sample2Button.Text = app.sample2{4};
            app.sampleArray{2} = app.sample2;

            updateAllDropDowns(app,2);
            end
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            placeholder =  assignMusicFile(app);

            if ~isequal(placeholder, 0)
            app.sample3 = placeholder;
            app.Sample3Button.Text = app.sample3{4};
            app.sampleArray{3} = app.sample3;

            updateAllDropDowns(app,3);
            end
            
        end

        % Button pushed function: LoadButton_4
        function LoadButton_4Pushed(app, event)
            placeholder =  assignMusicFile(app);

            if ~isequal(placeholder, 0)
            app.sample4 = placeholder;
            app.Sample4Button.Text = app.sample4{4};
            app.sampleArray{4} = app.sample4;

            updateAllDropDowns(app,4);
            end
            
        end

        % Button pushed function: LoadButton_5
        function LoadButton_5Pushed(app, event)
            placeholder =  assignMusicFile(app);

            if ~isequal(placeholder, 0)
            app.sample5 = placeholder;
            app.Sample5Button.Text = app.sample5{4};
            app.sampleArray{5} = app.sample5;

            updateAllDropDowns(app,5);
            end
        end

        % Button pushed function: LoadButton_6
        function LoadButton_6Pushed(app, event)
            placeholder =  assignMusicFile(app);

            if ~isequal(placeholder, 0)
            app.sample6 = placeholder;
            app.Sample6Button.Text = app.sample6{4};
            app.sampleArray{6} = app.sample6;

            updateAllDropDowns(app,6);
            end
        end

        % Button pushed function: LoadButton_7
        function LoadButton_7Pushed(app, event)
            placeholder =  assignMusicFile(app);

            if ~isequal(placeholder, 0)
            app.sample7 = placeholder;
            app.Sample7Button.Text = app.sample7{4};
            app.sampleArray{7} = app.sample7;

            updateAllDropDowns(app,7);
            end
        end

        % Button pushed function: LoadButton_8
        function LoadButton_8Pushed(app, event)
            placeholder =  assignMusicFile(app);

            if ~isequal(placeholder, 0)
            app.sample8 = placeholder;
            app.Sample8Button.Text = app.sample8{4};
            app.sampleArray{8} = app.sample8;

            updateAllDropDowns(app,8);
            end
        end

        % Button pushed function: LoadButton_9
        function LoadButton_9Pushed(app, event)
            placeholder =  assignMusicFile(app);

            if ~isequal(placeholder, 0)
            app.sample9 = placeholder;
            app.Sample9Button.Text = app.sample9{4};
            app.sampleArray{9} = app.sample9;

            updateAllDropDowns(app,9);
            end
        end

        % Button pushed function: Sample1Button
        function Sample1ButtonPushed(app, event)
            sampleButtonPushed(app,1);
        end

        % Button pushed function: Sample2Button
        function Sample2ButtonPushed(app, event)
            sampleButtonPushed(app,2);
        end

        % Button pushed function: Sample3Button
        function Sample3ButtonPushed(app, event)
            sampleButtonPushed(app,3);
        end

        % Button pushed function: Sample4Button
        function Sample4ButtonPushed(app, event)
            sampleButtonPushed(app,4);
        end

        % Button pushed function: Sample5Button
        function Sample5ButtonPushed(app, event)
            sampleButtonPushed(app,5);
        end

        % Button pushed function: Sample6Button
        function Sample6ButtonPushed(app, event)
            sampleButtonPushed(app,6);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

        if (~isempty(app.currentSample))
        if (app.startButtonPushed)
            % Stop playback
            app.StartButton.Text = 'Start';
            app.startButtonPushed = false;
            stop(app.currentSample{3});
        else
            % Start playback immediately (delay already applied in Save button)
            app.StartButton.Text = 'Stop';
            app.startButtonPushed = true;
            play(app.currentSample{3});
        end
        end
        end

        % Button pushed function: Sample7Button
        function Sample7ButtonPushed(app, event)
            sampleButtonPushed(app,7);
        end

        % Button pushed function: Sample8Button
        function Sample8ButtonPushed(app, event)
            sampleButtonPushed(app,8);
        end

        % Button pushed function: Sample9Button
        function Sample9ButtonPushed(app, event)
            sampleButtonPushed(app,9);
        end

        % Value changed function: PlaybackDirectionSwitch
        function PlaybackDirectionSwitchValueChanged(app, event)
             value = app.PlaybackDirectionSwitch.Value;
             app.reversePlayback = strcmp(value, 'Backward'); 
        end

        % Clicked callback: SampleDropDown_3
        function SampleDropDown_3Clicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: PlayButton_2
        function PlayButton_2ValueChanged(app, event)
            %Used for when you push a sample button, so that it can be
            %graphed and played
           
            %Things to keep in mind
            %Value is a 1x2 cell array with the first index being the
            %sample. Thus, only the first index can be taken for sample
            %use. The second is for indexing in the dropdown
            %
            
          if (~app.SampleDropDown_3.Value{2} == 0)                          %dropdown menu
              app.currentSample = app.SampleDropDown_3.Value{1};
              audioData = app.currentSample{1};
              sampleRate = app.currentSample{2};
          if app.reversePlayback                                            %audio reversal
              audioData = flip(audioData);
          end
             newSampleRate = sampleRate * app.playbackSpeed;                %playback rate 
             numChannels = size(audioData, 2); 
             processedAudio = audioData; 
          if app.feedbackLevel > 0 && app.delayTime > 0                     %feedback/delay
             delaySamples = round(app.delayTime * sampleRate); 
             feedbackAmount = app.feedbackLevel / 100; 
             echoAudio = zeros(size(audioData));                            %audio buffer
          for i = delaySamples+1:length(audioData)
                echoAudio(i, :) = feedbackAmount * audioData(i - delaySamples, :);
          end
              audioData = audioData + echoAudio;
          end
              audioData = audioData * app.volumeLevel;                      %input from dial adjusts volume

                if max(abs(audioData(:))) > 1                               %prevent clipping
                    audioData = audioData / max(abs(audioData(:)));  
                end  
             time = (0:length(audioData)-1) / newSampleRate;
                darkPurple = [0.3, 0, 0.5]; 
                                                                            %graph color change
             if app.displaySpectrogram
             windowLength = 1024; 
               overlapLength = windowLength / 2;
               nfft = 2048; 
                       
               [s, f, t] = spectrogram(audioData(:,1), windowLength, overlapLength, nfft, sampleRate);
                                                                            %plot the spectrogram
               axes(app.UIAxes2); 
               imagesc(app.UIAxes2,t, f, 10*log10(abs(s).^2)); 
               xlabel(app.UIAxes2,'Time (seconds)');
               ylabel(app.UIAxes2,'Frequency (Hz)');
               title(app.UIAxes2,'Spectrogram');
             else

             plot(app.UIAxes2, time, audioData, 'Color', darkPurple);         %waveform ploting
             xlabel(app.UIAxes2, 'Time (seconds)');
             ylabel(app.UIAxes2, 'Amplitude');
             title(app.UIAxes2, 'Waveform');

             end
             app.currentSample{1} = audioData; 
             app.currentSample{2} = newSampleRate;
             app.currentSample{3} = audioplayer(audioData, newSampleRate);
             %Once again, audiodata (5) is inaccurate

             %Added this line so you can save stuff
             app.modifierSample = app.currentSample;

          end  
          if ~isempty(app.currentSample) && isplaying(app.currentSample{3}) %stop current playback and override with new audio
              stop(app.currentSample{3}); 
          end


        end

        % Value changed function: PlaybackSpeedSlider
        function PlaybackSpeedSliderValueChanged(app, event)
            value = app.PlaybackSpeedSlider.Value;
            app.playbackSpeed = value;
        end

        % Value changed function: FeedbackLevelKnob
        function FeedbackLevelKnobValueChanged(app, event)
            value = app.FeedbackLevelKnob.Value;
            app.feedbackLevel = value;
        end

        % Value changed function: VolumeKnob
        function VolumeKnobValueChanged(app, event)
             value = app.VolumeKnob.Value;  
            app.volumeLevel = value / 100;  
            if ~isempty(app.currentSample) && isplaying(app.currentSample{3})
                stop(app.currentSample{3});  
                audioData = app.currentSample{1};
                newSampleRate = app.currentSample{2};
                audioData = audioData * app.volumeLevel;
                if max(abs(audioData(:))) > 1
                    audioData = audioData / max(abs(audioData(:)));
                end
                app.currentSample{3} = audioplayer(audioData, newSampleRate);
                play(app.currentSample{3});
            end
   
        end

        % Value changed function: DelayTimesecKnob
        function DelayTimesecKnobValueChanged(app, event)
            value = app.DelayTimesecKnob.Value;
            app.delayTime = value;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            %All of the actual graphing stuff is in the slider callback.
            %This lets the user see the graph as they change it on the
            %slider, which is pretty rad

            sound(app.generatedSample{1},app.generatedSample{2});

        end

        % Value changed function: HzSlider_4
        function HzSlider_4ValueChanged(app, event)
              value = event.Value;
    app.eqGains.Hz50 = value;  % Update 25Hz gain
    applyEqualization(app);
       
        end

        % Value changed function: HzSlider
        function HzSliderValueChanged(app, event)
           value = event.Value;
    app.eqGains.Hz125 = value;  % Update 25Hz gain
    applyEqualization(app);
         
        end

        % Value changed function: HzSlider_2
        function HzSlider_2ValueChanged(app, event)
           value = event.Value;
    app.eqGains.Hz250 = value;  % Update 25Hz gain
    applyEqualization(app);
          
        end

        % Value changed function: HzSlider_3
        function HzSlider_3ValueChanged(app, event)
          value = event.Value;
    app.eqGains.Hz500 = value;  % Update 25Hz gain
    applyEqualization(app);
           
        end

        % Value changed function: kHzSlider
        function kHzSliderValueChanged(app, event)
           value = event.Value;
    app.eqGains.Hz1000 = value;  % Update 25Hz gain
    applyEqualization(app);
        
        end

        % Value changed function: kHzSlider_2
        function kHzSlider_2ValueChanged(app, event)
           value = event.Value;
    app.eqGains.Hz2000 = value;  % Update 25Hz gain
    applyEqualization(app);
        
        end

        % Size changed function: EqualizerPanel
        function EqualizerPanelSizeChanged(app, event)
            position = app.EqualizerPanel.Position;
             
        end

        % Button pushed function: ChopAudioButton
        function ChopAudioButtonPushed(app, event)


        function audioChopper(app, chopStart, chopStop)
            app.SampleUsedDropDown.Value

            app.currentSample = app.SampleUsedDropDown.Value{1};

            time = (0:length(app.currentSample{1})-1) / app.currentSample{2};
            
            [~, startSample] = min(abs(time - chopStart)); % Find the index of the minimum absolute difference
            [~, endSample] = min(abs(time - chopStop)); % Find the index of the minimum absolute difference

            segments = app.currentSample{1}(startSample:endSample,:);
            app.currentSample{1} = segments;

            app.currentSample{3} = audioplayer(app.currentSample{1},app.currentSample{2});

            app.choppedAudio = app.currentSample;


            %Alert: the audioinfo in currentsample is currently unusable
            %because it is inaccurate. Don't use it


        end

        timey = length(app.SampleUsedDropDown.Value{1}{1})/app.SampleUsedDropDown.Value{1}{2};

        if app.SampleUsedDropDown.Value{2} == 0
                uialert(app.UIFigure, 'Please load an audio file first.', 'Error');
                return;
        end
            
            % Retrieve the chop duration start and stop values from the UI
            chopStart = abs(app.StarttimesecEditField.Value);
            chopStop = abs(app.StoptimesecEditField.Value);
            % Validate the input values
        if ~(isnumeric(chopStart) && isnumeric(chopStop)) 
                uialert(app.UIFigure, 'Enter valid durations: both > 0 and stop >= start.', 'Error');
                return;
        end

        if chopStop < chopStart
            uialert(app.UIFigure, 'Enter valid durations: both > 0 and stop >= start.', 'Error');
            return;
        end

        if chopStop > timey | chopStart > timey
            uialert(app.UIFigure, 'Enter valid durations: stop or start is larger than sample', 'Error');
            return;
        end

        audioChopper(app,chopStart,chopStop);

        
        %go to save button 3
        end

        % Callback function
        function SaveButtonPushed(app, event)
         
           
        end

        % Button pushed function: SpliceAudioButton
        function SpliceAudioButtonPushed(app, event)
            

        if app.SampleDropDown_4.Value{2} ~= 0

            partialEmptiness = false;

               %find a way to find the samples you need to work with
               
               switch app.nonSelectedBaseSamples(1)

                   case 1 %[1,2,3]
                       
                    baseSample = app.SampleDropDown_4.Value{1};
                    firstSample = app.SampleDropDown_7.Value{1};
                    firstSampleTime = app.numValueEF2;
                    secondSample = app.SampleDropDown_8.Value{1};
                    secondSampleTime = app.numValueEF3;

                   case 2 %[2,1,3]
                       
                    baseSample = app.SampleDropDown_7.Value{1};
                    firstSample = app.SampleDropDown_4.Value{1};
                    firstSampleTime = app.numValueEF1;
                    secondSample = app.SampleDropDown_8.Value{1};
                    secondSampleTime = app.numValueEF3;
                    
                   case 3 %[3,1,2]
                       
                    baseSample = app.SampleDropDown_8.Value{1};
                    firstSample = app.SampleDropDown_4.Value{1};
                    firstSampleTime = app.numValueEF1;
                    secondSample = app.SampleDropDown_7.Value{1};
                    secondSampleTime = app.numValueEF2;
                    
                    
               end
         
         %At least one of the two non-base samples need to be selected for
         %splicing to happen

         if isempty(firstSampleTime) & isempty(secondSampleTime)
             uialert(app.UIFigure, 'At least one time besides the base sample must be defined for splicing.', 'Error');
             return;
         elseif isempty(firstSampleTime)
             firstSample = secondSample;
             secondSampleTime = secondSampleTime;
             partialEmptiness = false;
         elseif isempty(secondSampleTime)
             partialEmptiness = true;
         end

      
         %This is where the splicing part actually happens
         if partialEmptiness
             %baseSample
             %firstSample
             %firstSampleTime

             %Make the samples the same sample rate. If they arent then
             %resample the nonbase sample
             if (baseSample{2} ~= firstSample{2})
                 [p,q] = rat(baseSample{2} / firstSample{2});
                 firstSample{1} = resample(firstSample{1},p,q);
                 firstSample{2} = baseSample{2};
                 %firstsample notoverlap,firstsample overlapping,
                 %secondsample overlapping,secondsample notoverlapping
             
             end

                 %find the index of the first sample to play at
                 ratio = firstSampleTime / (length(baseSample{1})/baseSample{2});
                 baseSampleFirstIndex = ratio * length(baseSample{1});

                 if baseSampleFirstIndex == 0
                    baseSampleFirstIndex = 1;
                 end

                 baseSampleFinalIndex = baseSampleFirstIndex + length(firstSample{1});
                 

                 %Splicing two samples,
                 %have to extend sample
                 if baseSampleFinalIndex > length(baseSample{1})

                     %For convenience, everything is made stereo

                     sizeSample = size(firstSample{1});
                     sizeBase = size(baseSample{1});

                     if sizeBase(2) ~= 2

                        baseSample{1} = [baseSample{1},baseSample{1}];
                         
                     end

                     if sizeSample(2) ~= 2

                        firstSample{1} = [firstSample{1},firstSample{1}];

                     end
                    

                     unaddedBaseSample = baseSample{1}(1:baseSampleFirstIndex,:);

                     combinedBaseSample = baseSample{1}(baseSampleFirstIndex+1 : end, :);
                     combinedFirstSample = firstSample{1}(1:length(combinedBaseSample), :);
                     combinedSample = combinedBaseSample + combinedFirstSample;
                     combinedSample = combinedSample ./2;
                     
                     %Variable size zero array

                     pureFirstSample = firstSample{1}(length(combinedBaseSample)+1:end, :);
                     emptyBaseSample = zeros(length(pureFirstSample), 2);
                     pureFirstSample = emptyBaseSample + pureFirstSample;

                     %dont use the audio data (as usual i suppose)
                     %By that I mean the stuff that says the length of the
                     %audio and duration, mr. teacher. The actual sample
                     %and player works, which is what matters

                     app.splicedSample = baseSample;
                     app.splicedSample{1} = [unaddedBaseSample; combinedSample; pureFirstSample];
                     app.splicedSample{3} = audioplayer(app.splicedSample{1},app.splicedSample{2});

                     return;
                     
                 else
                     sizeSample = size(firstSample{1});
                     sizeBase = size(baseSample{1});

                     %For convenience, everything is made stereo

                     if sizeBase(2) ~= 2

                        baseSample{1} = [baseSample{1},baseSample{1}];
                         
                     end

                     if sizeSample(2) ~= 2

                        firstSample{1} = [firstSample{1},firstSample{1}];

                     end

                     unaddedBaseSample = baseSample{1}(1:baseSampleFirstIndex, :);

                     combinedBaseSample = baseSample{1}(baseSampleFirstIndex+1 : baseSampleFirstIndex+length(firstSample{1}), :);
                     combinedFirstSample = firstSample{1};
                     combinedSample = combinedBaseSample + combinedFirstSample;
                     combinedSample = combinedSample ./2;
                     
                     
                     pureBaseSample = baseSample{1}(baseSampleFirstIndex + length(combinedBaseSample)+1:end,:);
                     
                     app.splicedSample = baseSample;
                     app.splicedSample{1} = [unaddedBaseSample; combinedSample; pureBaseSample];
                     app.splicedSample{3} = audioplayer(app.splicedSample{1},app.splicedSample{2});

                     return;
                    
                 end


         else
             %

             %Make the samples the same sample rate. If they arent then
             %resample the nonbase sample
             if (baseSample{2} ~= firstSample{2})
                 [p,q] = rat(baseSample{2} / firstSample{2});
                 firstSample{1} = resample(firstSample{1},p,q);
                 firstSample{2} = baseSample{2};
                 %firstsample notoverlap,firstsample overlapping,
                 %secondsample overlapping,secondsample notoverlapping
             
             end

             if (baseSample{2} ~= secondSample{2})
                 [p,q] = rat(baseSample{2} / secondSample{2});
                 secondSample{1} = resample(secondSample{1},p,q);
                 secondSample{2} = baseSample{2};
                 %firstsample notoverlap,firstsample overlapping,
                 %secondsample overlapping,secondsample notoverlapping
             
             end

                 %find the index of the first sample to play at
                 ratio = firstSampleTime / (length(baseSample{1})/baseSample{2});
                 baseSampleFirstIndex = ratio * length(baseSample{1});

                 if baseSampleFirstIndex == 0
                    baseSampleFirstIndex = 1;
                 end

                 baseSampleFinalIndex = baseSampleFirstIndex + length(firstSample{1});
                 
                 %splice the first sample

               if baseSampleFinalIndex > length(baseSample{1})

                     %For convenience, everything is made stereo

                     sizeSample = size(firstSample{1});
                     sizeBase = size(baseSample{1});

                     if sizeBase(2) ~= 2

                        baseSample{1} = [baseSample{1},baseSample{1}];
                         
                     end

                     if sizeSample(2) ~= 2

                        firstSample{1} = [firstSample{1},firstSample{1}];

                     end
                    

                     unaddedBaseSample = baseSample{1}(1:baseSampleFirstIndex,:);

                     combinedBaseSample = baseSample{1}(baseSampleFirstIndex+1 : end, :);
                     combinedFirstSample = firstSample{1}(1:length(combinedBaseSample), :);
                     combinedSample = combinedBaseSample + combinedFirstSample;
                     combinedSample = combinedSample ./2;
                     
                     %Variable size zero array

                     pureFirstSample = firstSample{1}(length(combinedBaseSample)+1:end, :);
                     emptyBaseSample = zeros(length(pureFirstSample), 2);
                     pureFirstSample = emptyBaseSample + pureFirstSample;

                     %dont use the audio data (as usual i suppose)
                     %By that I mean the stuff that says the length of the
                     %audio and duration, mr. teacher. The actual sample
                     %and player works, which is what matters

                     app.splicedSample = baseSample;
                     app.splicedSample{1} = [unaddedBaseSample; combinedSample; pureFirstSample];
                     app.splicedSample{3} = audioplayer(app.splicedSample{1},app.splicedSample{2});

                     return;
                     
                 else
                     sizeSample = size(firstSample{1});
                     sizeBase = size(baseSample{1});

                     %For convenience, everything is made stereo

                     if sizeBase(2) ~= 2

                        baseSample{1} = [baseSample{1},baseSample{1}];
                         
                     end

                     if sizeSample(2) ~= 2

                        firstSample{1} = [firstSample{1},firstSample{1}];

                     end

                     unaddedBaseSample = baseSample{1}(1:baseSampleFirstIndex, :);

                     combinedBaseSample = baseSample{1}(baseSampleFirstIndex+1 : baseSampleFirstIndex+length(firstSample{1}), :);
                     combinedFirstSample = firstSample{1};
                     combinedSample = combinedBaseSample + combinedFirstSample;
                     combinedSample = combinedSample ./2;
                     
                     
                     pureBaseSample = baseSample{1}(baseSampleFirstIndex + length(combinedBaseSample)+1:end,:);
                     
                     app.splicedSample = baseSample;
                     app.splicedSample{1} = [unaddedBaseSample; combinedSample; pureBaseSample];
                     app.splicedSample{3} = audioplayer(app.splicedSample{1},app.splicedSample{2});


               end

               %splice the second sample

               %find the index of the first sample to play at
                 ratio = secondSampleTime / (length(baseSample{1})/baseSample{2});
                 baseSampleFirstIndex = ratio * length(baseSample{1});

                 if baseSampleFirstIndex == 0
                    baseSampleFirstIndex = 1;
                 end

                 baseSampleFinalIndex = baseSampleFirstIndex + length(secondSample{1});

                 firstSample = secondSample;
                 baseSample = app.splicedSample;
                 
                 %splice the second sample

                 if baseSampleFinalIndex > length(baseSample{1})

                     %For convenience, everything is made stereo

                     sizeSample = size(firstSample{1});
                     sizeBase = size(baseSample{1});

                     if sizeBase(2) ~= 2

                        baseSample{1} = [baseSample{1},baseSample{1}];
                         
                     end

                     if sizeSample(2) ~= 2

                        firstSample{1} = [firstSample{1},firstSample{1}];

                     end
                    

                     unaddedBaseSample = baseSample{1}(1:baseSampleFirstIndex,:);

                     combinedBaseSample = baseSample{1}(baseSampleFirstIndex+1 : end, :);
                     combinedFirstSample = firstSample{1}(1:length(combinedBaseSample), :);
                     combinedSample = combinedBaseSample + combinedFirstSample;
                     combinedSample = combinedSample ./2;
                     
                     %Variable size zero array

                     pureFirstSample = firstSample{1}(length(combinedBaseSample)+1:end, :);
                     emptyBaseSample = zeros(length(pureFirstSample), 2);
                     pureFirstSample = emptyBaseSample + pureFirstSample;

                     %dont use the audio data (as usual i suppose)
                     %By that I mean the stuff that says the length of the
                     %audio and duration, mr. teacher. The actual sample
                     %and player works, which is what matters

                     app.splicedSample = baseSample;
                     app.splicedSample{1} = [unaddedBaseSample; combinedSample; pureFirstSample];
                     app.splicedSample{3} = audioplayer(app.splicedSample{1},app.splicedSample{2});

                     return;
                     
                 else
                     sizeSample = size(firstSample{1});
                     sizeBase = size(baseSample{1});

                     %For convenience, everything is made stereo

                     if sizeBase(2) ~= 2

                        baseSample{1} = [baseSample{1},baseSample{1}];
                         
                     end

                     if sizeSample(2) ~= 2

                        firstSample{1} = [firstSample{1},firstSample{1}];

                     end

                     unaddedBaseSample = baseSample{1}(1:baseSampleFirstIndex, :);

                     combinedBaseSample = baseSample{1}(baseSampleFirstIndex+1 : baseSampleFirstIndex+length(firstSample{1}), :);
                     combinedFirstSample = firstSample{1};
                     combinedSample = combinedBaseSample + combinedFirstSample;
                     combinedSample = combinedSample ./2;
                     
                     
                     pureBaseSample = baseSample{1}(baseSampleFirstIndex + length(combinedBaseSample)+1:end,:);
                     
                     app.splicedSample = baseSample;
                     app.splicedSample{1} = [unaddedBaseSample; combinedSample; pureBaseSample];
                     app.splicedSample{3} = audioplayer(app.splicedSample{1},app.splicedSample{2});

                     return;
                    
               end


         end

     else
           uialert(app.UIFigure, 'No file selected.', 'Error');
     end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            
            if ~(isempty(app.splicedSample))
                saveToSample(app,app.SampleSavedToDropDown_4.Value,app.splicedSample);
            end

            app.splicedSample = [];
                
        end

        % Button pushed function: SaveButton_7
        function SaveButton_7Pushed(app, event)
            if (~isempty(app.choppedAudio))
             
        
             saveToSample(app,app.SampleSavedToDropDown.Value,app.choppedAudio);
             
           end
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            value = app.FrequencySlider.Value;
            value = value * 25;
            app.hertzSliderValue = value;

            makeAndGraphSynth(app);
            

        end

        % Button pushed function: SaveButton_5
        function SaveButton_5Pushed(app, event)
           
           saveToSample(app,app.SampleSavedToDropDown_3.Value,app.generatedSample);

            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject.Text;

            if strcmp(selectedButton, 'Sine Wave')

                app.waveSelected = 1;

            elseif strcmp(selectedButton, 'Square Wave')

                app.waveSelected = 2;
            
            end

            makeAndGraphSynth(app);
            
        end

        % Button pushed function: SaveButton_6
        function SaveButton_6Pushed(app, event)
            if ~isempty(app.modifierSample)
                saveToSample(app,app.SampleSavedToDropDown_2.Value,app.modifierSample);
            end
        end

        % Button pushed function: SaveSelectedSampleasFileButton
        function SaveSelectedSampleasFileButtonPushed(app, event)
            if isempty(app.currentSample)
                uialert(app.UIFigure, 'No file selected.', 'Error');
            else
                [filename, filepath] = uiputfile({'*.wav', 'WAV files (*.wav)'}, ...
                'Save Audio File', 'sine_wave.wav');
            
                % Check if the user pressed Cancel (filename will be 0 if canceled)
                if filename ~= 0
                    % Full path to save the file
                    fullFilePath = fullfile(filepath, filename);
                
                    % Save the audio file using audiowrite
                    audiowrite(fullFilePath, app.currentSample{1}, app.currentSample{2});
                
                    % Display a message confirming the save
                    disp(['File saved to: ', fullFilePath]);
                else
                    disp('File save was canceled, for some reason.');
                end
            end
        end

        % Callback function
        function EditFieldValueChanged(app, event)
       %This function isnt used
        end

        % Value changed function: EditField
        function EditFieldValueChanged2(app, event)
            

         [app.numValueEF1, app.EditField.Value] = ...
         editFieldCallbackProcesses(app,app.SampleDropDown_4.Value, app.EditField.Value);
           
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            
            [app.numValueEF2, app.EditField_2.Value] = ...
       editFieldCallbackProcesses(app,app.SampleDropDown_7.Value, app.EditField_2.Value);
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            
            [app.numValueEF3, app.EditField_3.Value] = ...
       editFieldCallbackProcesses(app,app.SampleDropDown_8.Value, app.EditField_3.Value);
            
        end

        % Value changed function: SampleDropDown_4
        function SampleDropDown_4ValueChanged(app, event)
            value = app.SampleDropDown_4.Value;
            %{
            if value{2} ~= 0
                time = (length(value{1}{1}) /value{1}{2});
                app.sampleValueEF1 = time;
                minutes = floor(time / 60);
                seconds = round(mod(time, 60));
    
                newFieldValue = sprintf('/ %01d:%02d', minutes, seconds);
                app.Label_3.Text = newFieldValue;
                

            end
            %}
            
        end

        % Selection changed function: BaseSampleButtonGroup
        function BaseSampleButtonGroupSelectionChanged(app, event)
            selectedButton = app.BaseSampleButtonGroup.SelectedObject.Text;
            
            if strcmp(selectedButton, '1.')
                app.selectedBaseSample = 1;
                app.nonSelectedBaseSamples = [1,2,3];
                app.numValueEF1 = [];
                app.numValueEF2 = [];
                app.numValueEF3 = [];
                app.EditField.Value = '';
                app.EditField_2.Value = '';
                app.EditField_3.Value = '';
                app.splicedSample = [];
            elseif strcmp(selectedButton, '2.')
                app.selectedBaseSample = 2;
                app.nonSelectedBaseSamples = [2,1,3];
                app.numValueEF1 = [];
                app.numValueEF2 = [];
                app.numValueEF3 = [];
                app.EditField.Value = '';
                app.EditField_2.Value = '';
                app.EditField_3.Value = '';
                app.splicedSample = [];
            elseif strcmp(selectedButton, '3.')
                app.selectedBaseSample = 3;
                app.nonSelectedBaseSamples = [3,1,2];
                app.numValueEF1 = [];
                app.numValueEF2 = [];
                app.numValueEF3 = [];
                app.EditField.Value = '';
                app.EditField_2.Value = '';
                app.EditField_3.Value = '';
                app.splicedSample = [];
            end
        end

        % Callback function
        function DisplayButtonPushed(app, event)
            if app.SampleDropDown_6.Value{2} ~= 0
                if app.displaySpectrogram

                 %Compute the spectrogram
                    windowLength = 1024; % Length of each window for STFT
                    overlapLength = windowLength / 2; % 50% overlap
                    nfft = 2048; % Number of FFT points
                    
                    %Plots only the left array
                    
                    [s, f, t] = spectrogram(app.SampleDropDown_6.Value{1}{1}(:,1), windowLength, overlapLength, nfft, app.SampleDropDown_6.Value{1}{2});
                    
                    %Plot the spectrogram
                    axes(app.UIAxes2); % Make sure UIAxes2 is the active axes
                    imagesc(app.UIAxes2,t, f, 10*log10(abs(s).^2)); % Plot in dB scale
                    xlabel(app.UIAxes2,'Time (s)');
                    ylabel(app.UIAxes2,'Frequency (Hz)');
                    title(app.UIAxes2,'Spectrogram');

                else
                     audioData = app.SampleDropDown_6.Value{1}{1};
                     time = (0:length(audioData)-1) / app.SampleDropDown_6.Value{1}{2};
                     plot(app.UIAxes2, time, audioData);                            %waveform ploting
                     xlabel(app.UIAxes2, 'Time (seconds)');
                     ylabel(app.UIAxes2, 'Amplitude');
                     title(app.UIAxes2, 'Waveform');
                end
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)

            if strcmp(app.Switch.Value,'On')
                app.displaySpectrogram = true;
            elseif strcmp(app.Switch.Value,'Off')
                app.displaySpectrogram = false;
            end

            if ~isempty(app.currentSampleDisplayed)
                sampleButtonPushed(app,app.currentSampleDisplayed);
            end
            
        end

        % Callback function
        function LoadButton_11Pushed(app, event)
            [filename,filepath] =uigetfile({'*.jpg;*.png;*.bmp'}, 'Select File to Open');
            fullname = [filepath, filename];
            app.image = imread(fullname);
            app.image = flip(app.image);
            app.ImageLabel.Text = filename;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Waveform')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.SamplesSelectionPanel = uipanel(app.UIFigure);
            app.SamplesSelectionPanel.Title = 'Samples Selection';
            app.Sample9Button = uibutton(app.SamplesSelectionPanel, 'push');
            app.Sample9Button.ButtonPushedFcn = createCallbackFcn(app, @Sample9ButtonPushed, true);
            app.Sample9Button.Text = 'Sample 9';
            app.Sample8Button = uibutton(app.SamplesSelectionPanel, 'push');
            app.Sample8Button.ButtonPushedFcn = createCallbackFcn(app, @Sample8ButtonPushed, true);
            app.Sample8Button.Text = 'Sample 8';
            app.Sample7Button = uibutton(app.SamplesSelectionPanel, 'push');
            app.Sample7Button.ButtonPushedFcn = createCallbackFcn(app, @Sample7ButtonPushed, true);
            app.Sample7Button.Text = 'Sample 7';
            app.Sample6Button = uibutton(app.SamplesSelectionPanel, 'push');
            app.Sample6Button.ButtonPushedFcn = createCallbackFcn(app, @Sample6ButtonPushed, true);
            app.Sample6Button.Text = 'Sample 6';
            app.Sample5Button = uibutton(app.SamplesSelectionPanel, 'push');
            app.Sample5Button.ButtonPushedFcn = createCallbackFcn(app, @Sample5ButtonPushed, true);
            app.Sample5Button.Text = 'Sample 5';
            app.Sample4Button = uibutton(app.SamplesSelectionPanel, 'push');
            app.Sample4Button.ButtonPushedFcn = createCallbackFcn(app, @Sample4ButtonPushed, true);
            app.Sample4Button.Text = 'Sample 4';
            app.Sample3Button = uibutton(app.SamplesSelectionPanel, 'push');
            app.Sample3Button.ButtonPushedFcn = createCallbackFcn(app, @Sample3ButtonPushed, true);
            app.Sample3Button.Text = 'Sample 3';
            app.Sample2Button = uibutton(app.SamplesSelectionPanel, 'push');
            app.Sample2Button.ButtonPushedFcn = createCallbackFcn(app, @Sample2ButtonPushed, true);
            app.Sample2Button.Text = 'Sample 2';
            app.Sample1Button = uibutton(app.SamplesSelectionPanel, 'push');
            app.Sample1Button.ButtonPushedFcn = createCallbackFcn(app, @Sample1ButtonPushed, true);
            app.Sample1Button.Text = 'Sample 1';
            app.LoadButton_9 = uibutton(app.SamplesSelectionPanel, 'push');
            app.LoadButton_9.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_9Pushed, true);
            app.LoadButton_9.Text = 'Load';
            app.LoadButton_8 = uibutton(app.SamplesSelectionPanel, 'push');
            app.LoadButton_8.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_8Pushed, true);
            app.LoadButton_8.Text = 'Load';
            app.LoadButton_7 = uibutton(app.SamplesSelectionPanel, 'push');
            app.LoadButton_7.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_7Pushed, true);
            app.LoadButton_7.Text = 'Load';
            app.LoadButton_6 = uibutton(app.SamplesSelectionPanel, 'push');
            app.LoadButton_6.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_6Pushed, true);
            app.LoadButton_6.Text = 'Load';
            app.LoadButton_5 = uibutton(app.SamplesSelectionPanel, 'push');
            app.LoadButton_5.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_5Pushed, true);
            app.LoadButton_5.Text = 'Load';
            app.LoadButton_4 = uibutton(app.SamplesSelectionPanel, 'push');
            app.LoadButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_4Pushed, true);
            app.LoadButton_4.Text = 'Load';
            app.LoadButton_3 = uibutton(app.SamplesSelectionPanel, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.LoadButton_2 = uibutton(app.SamplesSelectionPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.LoadButton_1 = uibutton(app.SamplesSelectionPanel, 'push');
            app.LoadButton_1.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_1Pushed, true);
            app.LoadButton_1.Text = 'Load';
            app.EffectsAndSampleModificationPanel = uipanel(app.UIFigure);
            app.EffectsAndSampleModificationPanel.Title = 'Effects And Sample Modification';
            app.SampleDropDown_3Label = uilabel(app.EffectsAndSampleModificationPanel);
            app.SampleDropDown_3Label.Text = 'Sample #';
            app.SampleDropDown_3 = uidropdown(app.EffectsAndSampleModificationPanel);
            app.SampleDropDown_3.Items = {'Sample 1'};
            app.SampleDropDown_3.ItemsData = {'a'};
            app.SampleDropDown_3.ClickedFcn = createCallbackFcn(app, @SampleDropDown_3Clicked, true);
            app.SampleDropDown_3.Value = 'a';
            app.VolumeKnobLabel = uilabel(app.EffectsAndSampleModificationPanel);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.EffectsAndSampleModificationPanel, 'continuous');
            app.VolumeKnob.Limits = [0 200];
            app.VolumeKnob.ValueChangedFcn = createCallbackFcn(app, @VolumeKnobValueChanged, true);
            app.PlaybackDirectionSwitchLabel = uilabel(app.EffectsAndSampleModificationPanel);
            app.PlaybackDirectionSwitchLabel.Text = 'Playback Direction';
            app.PlaybackDirectionSwitch = uiswitch(app.EffectsAndSampleModificationPanel, 'slider');
            app.PlaybackDirectionSwitch.Items = {'Forward', 'Backward'};
            app.PlaybackDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @PlaybackDirectionSwitchValueChanged, true);
            app.PlaybackDirectionSwitch.Value = 'Forward';
            app.PlaybackSpeedSliderLabel = uilabel(app.EffectsAndSampleModificationPanel);
            app.PlaybackSpeedSliderLabel.Text = 'Playback Speed';
            app.PlaybackSpeedSlider = uislider(app.EffectsAndSampleModificationPanel);
            app.PlaybackSpeedSlider.Limits = [0.5 2];
            app.PlaybackSpeedSlider.MajorTicks = [0.5 0.75 1 1.25 1.5 1.75 2];
            app.PlaybackSpeedSlider.ValueChangedFcn = createCallbackFcn(app, @PlaybackSpeedSliderValueChanged, true);
            app.PlaybackSpeedSlider.MinorTicks = [0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1 1.05 1.1 1.15 1.2 1.25 1.3 1.35 1.4 1.45 1.5 1.55 1.6 1.65 1.7 1.75 1.8 1.85 1.9 1.95 2];
            app.PlaybackSpeedSlider.Value = 1;
            app.EqualizerPanel = uipanel(app.EffectsAndSampleModificationPanel);
            app.EqualizerPanel.Title = 'Equalizer';
            app.EqualizerPanel.SizeChangedFcn = createCallbackFcn(app, @EqualizerPanelSizeChanged, true);
            app.HzSliderLabel = uilabel(app.EqualizerPanel);
            app.HzSliderLabel.Text = '125 Hz';
            app.HzSlider = uislider(app.EqualizerPanel);
            app.HzSlider.Limits = [-3 3];
            app.HzSlider.ValueChangedFcn = createCallbackFcn(app, @HzSliderValueChanged, true);
            app.HzSlider_4Label = uilabel(app.EqualizerPanel);
            app.HzSlider_4Label.Text = '50Hz';
            app.HzSlider_4 = uislider(app.EqualizerPanel);
            app.HzSlider_4.Limits = [-3 3];
            app.HzSlider_4.ValueChangedFcn = createCallbackFcn(app, @HzSlider_4ValueChanged, true);
            app.HzSlider_2Label = uilabel(app.EqualizerPanel);
            app.HzSlider_2Label.Text = '250 Hz';
            app.HzSlider_2 = uislider(app.EqualizerPanel);
            app.HzSlider_2.Limits = [-3 3];
            app.HzSlider_2.ValueChangedFcn = createCallbackFcn(app, @HzSlider_2ValueChanged, true);
            app.HzSlider_3Label = uilabel(app.EqualizerPanel);
            app.HzSlider_3Label.Text = '500 Hz';
            app.HzSlider_3 = uislider(app.EqualizerPanel);
            app.HzSlider_3.Limits = [-3 3];
            app.HzSlider_3.ValueChangedFcn = createCallbackFcn(app, @HzSlider_3ValueChanged, true);
            app.kHzSliderLabel = uilabel(app.EqualizerPanel);
            app.kHzSliderLabel.Text = '1k Hz';
            app.kHzSlider = uislider(app.EqualizerPanel);
            app.kHzSlider.Limits = [-3 3];
            app.kHzSlider.ValueChangedFcn = createCallbackFcn(app, @kHzSliderValueChanged, true);
            app.kHzLabel = uilabel(app.EqualizerPanel);
            app.kHzLabel.Text = '2k Hz';
            app.kHzSlider_2 = uislider(app.EqualizerPanel);
            app.kHzSlider_2.Limits = [-3 3];
            app.kHzSlider_2.ValueChangedFcn = createCallbackFcn(app, @kHzSlider_2ValueChanged, true);
            app.FeedbackLevelLabel = uilabel(app.EffectsAndSampleModificationPanel);
            app.FeedbackLevelLabel.Text = 'Feedback Level (%)';
            app.FeedbackLevelKnob = uiknob(app.EffectsAndSampleModificationPanel, 'continuous');
            app.FeedbackLevelKnob.MajorTicks = [0 25 50 75 100];
            app.FeedbackLevelKnob.ValueChangedFcn = createCallbackFcn(app, @FeedbackLevelKnobValueChanged, true);
            app.DelayTimesecKnobLabel = uilabel(app.EffectsAndSampleModificationPanel);
            app.DelayTimesecKnobLabel.Text = 'Delay Time (sec.)';
            app.DelayTimesecKnob = uiknob(app.EffectsAndSampleModificationPanel, 'continuous');
            app.DelayTimesecKnob.Limits = [0 1];
            app.DelayTimesecKnob.MajorTicks = [0 0.1 0.2 0.3 0.5 1];
            app.DelayTimesecKnob.ValueChangedFcn = createCallbackFcn(app, @DelayTimesecKnobValueChanged, true);
            app.PlayButton_2 = uibutton(app.EffectsAndSampleModificationPanel, 'state');
            app.PlayButton_2.ValueChangedFcn = createCallbackFcn(app, @PlayButton_2ValueChanged, true);
            app.PlayButton_2.Text = 'Play';
            app.SaveButton_6 = uibutton(app.EffectsAndSampleModificationPanel, 'push');
            app.SaveButton_6.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_6Pushed, true);
            app.SaveButton_6.Text = 'Save';
            app.SampleSavedToDropDown_2Label = uilabel(app.EffectsAndSampleModificationPanel);
            app.SampleSavedToDropDown_2Label.Text = 'Sample Saved To';
            app.SampleSavedToDropDown_2 = uidropdown(app.EffectsAndSampleModificationPanel);
            app.SampleSavedToDropDown_2.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleSavedToDropDown_2.ItemsData = [1 2 3 4 5 6 7 8 9];
            app.SampleSavedToDropDown_2.Value = 1;
            app.ChoppingPanel = uipanel(app.UIFigure);
            app.ChoppingPanel.Title = 'Chopping';
            app.SampleUsedDropDownLabel = uilabel(app.ChoppingPanel);
            app.SampleUsedDropDownLabel.Text = 'Sample Used';
            app.SampleUsedDropDown = uidropdown(app.ChoppingPanel);
            app.SampleUsedDropDown.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleUsedDropDown.Value = 'Sample 1';
            app.SaveButton_7 = uibutton(app.ChoppingPanel, 'push');
            app.SaveButton_7.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_7Pushed, true);
            app.SaveButton_7.Text = 'Save';
            app.ChopAudioButton = uibutton(app.ChoppingPanel, 'push');
            app.ChopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ChopAudioButtonPushed, true);
            app.ChopAudioButton.Text = 'Chop Audio';
            app.StarttimesecEditFieldLabel = uilabel(app.ChoppingPanel);
            app.StarttimesecEditFieldLabel.Text = 'Start time (sec)';
            app.StarttimesecEditField = uieditfield(app.ChoppingPanel, 'numeric');
            app.StoptimesecEditFieldLabel = uilabel(app.ChoppingPanel);
            app.StoptimesecEditFieldLabel.Text = 'Stop time (sec)';
            app.StoptimesecEditField = uieditfield(app.ChoppingPanel, 'numeric');
            app.SampleSavedToDropDownLabel = uilabel(app.ChoppingPanel);
            app.SampleSavedToDropDownLabel.Text = 'Sample Saved To';
            app.SampleSavedToDropDown = uidropdown(app.ChoppingPanel);
            app.SampleSavedToDropDown.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleSavedToDropDown.ItemsData = [1 2 3 4 5 6 7 8 9];
            app.SampleSavedToDropDown.Value = 1;
            app.SynthesizerPanel = uipanel(app.UIFigure);
            app.SynthesizerPanel.Title = 'Synthesizer';
            app.UIAxes = uiaxes(app.SynthesizerPanel);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 'Time(seconds)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.ButtonGroup = uibuttongroup(app.SynthesizerPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SineWaveButton = uiradiobutton(app.ButtonGroup);
            app.SineWaveButton.Text = 'Sine Wave';
            app.SineWaveButton.Value = true;
            app.SquareWaveButton = uiradiobutton(app.ButtonGroup);
            app.SquareWaveButton.Text = 'Square Wave';
            app.SaveButton_5 = uibutton(app.SynthesizerPanel, 'push');
            app.SaveButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_5Pushed, true);
            app.SaveButton_5.Text = 'Save';
            app.FrequencySliderLabel = uilabel(app.SynthesizerPanel);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.SynthesizerPanel);
            app.FrequencySlider.Limits = [1 100];
            app.FrequencySlider.MajorTicks = [1 20 40 60 80 100];
            app.FrequencySlider.MajorTickLabels = {'25', '500', '1000', '1500', '2000', '2500'};
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.Value = 1;
            app.PlayButton = uibutton(app.SynthesizerPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.SampleSavedToDropDown_3Label = uilabel(app.SynthesizerPanel);
            app.SampleSavedToDropDown_3Label.Text = 'Sample Saved To';
            app.SampleSavedToDropDown_3 = uidropdown(app.SynthesizerPanel);
            app.SampleSavedToDropDown_3.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleSavedToDropDown_3.ItemsData = [1 2 3 4 5 6 7 8 9];
            app.SampleSavedToDropDown_3.Value = 1;
            app.AudioSplicingPanel = uipanel(app.UIFigure);
            app.AudioSplicingPanel.Title = 'Audio Splicing';
            app.Label_2 = uilabel(app.AudioSplicingPanel);
            app.Label_2.Text = 'Label #';
            app.StartTimeLabel = uilabel(app.AudioSplicingPanel);
            app.StartTimeLabel.Text = 'Start Time';
            app.SampleDropDown_4 = uidropdown(app.AudioSplicingPanel);
            app.SampleDropDown_4.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleDropDown_4.ValueChangedFcn = createCallbackFcn(app, @SampleDropDown_4ValueChanged, true);
            app.SampleDropDown_4.Value = 'Sample 1';
            app.SpliceAudioButton = uibutton(app.AudioSplicingPanel, 'push');
            app.SpliceAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SpliceAudioButtonPushed, true);
            app.SpliceAudioButton.Text = 'Splice Audio';
            app.SaveButton_2 = uibutton(app.AudioSplicingPanel, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SampleDropDown_7 = uidropdown(app.AudioSplicingPanel);
            app.SampleDropDown_7.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleDropDown_7.Value = 'Sample 1';
            app.SampleDropDown_8 = uidropdown(app.AudioSplicingPanel);
            app.SampleDropDown_8.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleDropDown_8.Value = 'Sample 1';
            app.EditField = uieditfield(app.AudioSplicingPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged2, true);
            app.EditField_2 = uieditfield(app.AudioSplicingPanel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.AudioSplicingPanel, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.BaseSampleButtonGroup = uibuttongroup(app.AudioSplicingPanel);
            app.BaseSampleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BaseSampleButtonGroupSelectionChanged, true);
            app.BaseSampleButtonGroup.Title = 'Base Sample';
            app.Button = uiradiobutton(app.BaseSampleButtonGroup);
            app.Button.Text = '1.';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.BaseSampleButtonGroup);
            app.Button_2.Text = '2.';
            app.Button_3 = uiradiobutton(app.BaseSampleButtonGroup);
            app.Button_3.Text = '3.';
            app.Label_4 = uilabel(app.AudioSplicingPanel);
            app.Label_4.Text = '1.';
            app.Label_5 = uilabel(app.AudioSplicingPanel);
            app.Label_5.Text = '2.';
            app.Label_6 = uilabel(app.AudioSplicingPanel);
            app.Label_6.Text = '3.';
            app.SampleSavedToDropDown_4Label = uilabel(app.AudioSplicingPanel);
            app.SampleSavedToDropDown_4Label.Text = 'Sample Saved To';
            app.SampleSavedToDropDown_4 = uidropdown(app.AudioSplicingPanel);
            app.SampleSavedToDropDown_4.Items = {'Sample 1', 'Sample 2', 'Sample 3', 'Sample 4', 'Sample 5', 'Sample 6', 'Sample 7', 'Sample 8', 'Sample 9'};
            app.SampleSavedToDropDown_4.ItemsData = [1 2 3 4 5 6 7 8 9];
            app.SampleSavedToDropDown_4.Value = 1;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveSelectedSampleasFileButton = uibutton(app.UIFigure, 'push');
            app.SaveSelectedSampleasFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSelectedSampleasFileButtonPushed, true);
            app.SaveSelectedSampleasFileButton.Text = 'Save Selected Sample as File';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.SpectrogramViewLabel = uilabel(app.UIFigure);
            app.SpectrogramViewLabel.Text = 'Spectrogram View';
            app.MainSampleLastSelectedLabel = uilabel(app.UIFigure);
            app.MainSampleLastSelectedLabel.Text = 'Main Sample Last Selected:';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectDraft5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end